classdef ModelExtract < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        TabGroup      matlab.ui.container.TabGroup
        ARMTab        matlab.ui.container.Tab
        TabGroup2     matlab.ui.container.TabGroup
        MLPTab_2      matlab.ui.container.Tab
        Panel_2       matlab.ui.container.Panel
        EditField_17  matlab.ui.control.EditField
        Button_11     matlab.ui.control.Button
        TextArea      matlab.ui.control.TextArea
        Button_10     matlab.ui.control.Button
        Panel         matlab.ui.container.Panel
        EditField_4   matlab.ui.control.EditField
        Button_8      matlab.ui.control.Button
        Button_9      matlab.ui.control.Button
        Button_7      matlab.ui.control.Button
        EditField_3   matlab.ui.control.EditField
        Button_6      matlab.ui.control.Button
    end

    
    properties (Access = public)
        Property % Description
        PTracesPath;
        SelectedFilePath;
        MLP_Arr=[10, 22, 55, 11,47, 59, 7, 33, 74, 4, 43, 16, -20, -1, -22, 93, 23, -46, 45, -14, -1, -26, 30, 58, 72, -7, -44, 49, -21, -41];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
           
        trace_undiv % Description
        SelectedFilePath_py % Description
        SelectedFilePath_py_arm_S % Description
        SelectedFilePath_py_arm_M % Description
        SelectedFilePath_py_arm_Y % Description
        SelectedFilePath_py_x86_S % Description
        SelectedFilePath_py_x86_M % Description
        SelectedFilePath_py_x86_Y % Description
        %%功耗曲线路径
        PTracesPath_arm_S % Description
        PTracesPath_arm_M % Description
        PTracesPath_arm_Y % Description
        PTracesPath_x86_S % Description
        PTracesPath_x86_M % Description
        PTracesPath_x86_Y % Description

        SelectedFilePath_arm_S % Description
        SelectedFilePath_arm_M % Description
        SelectedFilePath_arm_Y % Description
        SelectedFilePath_x86_S % Description
        SelectedFilePath_x86_M % Description
        SelectedFilePath_x86_Y % Description
        Real_MLP_structure % Description
        Predict_MLP_structure % Description
    end


    methods (Access = private)
        
function hamming_weight = HammingWeight(~,number,sig)%计算汉明距离
if sig==1
       single_float = single(number);
% 将单精度浮点数转换为 32 位无符号整数
    binary_string=bitget(typecast(single_float, 'uint32'), 1:32);
    binary_string= flip(binary_string);
    % 将无符号整数转换为二进制字符串
   mul=uint32([0 0 0 0 0 0 0 0 0 23 22 21 20 19 18 17 16 1 2 3 4 5 6 7 8 9 10 11 12 12 14 15]);  
    binary_string=binary_string.*mul;
    hamming_weight=sum(binary_string);
else
    binary_string=bitget(int32(number),1:32);
    binary_string= flip(binary_string);
    % 将无符号整数转换为二进制字符串
  mul=uint32([32 31 30 29 28 27 26 25 24 23 22 21 20 19 18 17 16 15 14 13 12 11 10 9 8 7 6 5 4 3 2 1]);
    binary_string=binary_string.*mul;
    hamming_weight=sum(binary_string);
end

end


function maxValues = analyze_w1_correlation(~,Power_traces, HWeight,w,n)%相关性分析
    % 初始化waitbar
    h = waitbar(0, ['第',num2str(n),'个权重']);
    % 初始化corparr_w1和maxValues
    [rows, cols] = size(Power_traces);
    corparr_w1 = zeros(255, cols);
    maxValues = zeros(255, 1);
  %  figure;
   % hold on;
     for i = 1:255
        for j = 1:cols
            corparr_w1(i, j) = corr2(HWeight(1:500, i), Power_traces(1:500, j));%corparr_w1的一行是一个可能的w
        end
        maxValues(i) = max(corparr_w1(i, :));
        waitbar(i/255);
      %  plot(corparr_w1(i, :),'b');
     end
   %  plot(corparr_w1(w+128, :),'r');

   %  hold off;
        close(h);
    % 绘制结果
    figure;
    plot(-127:127, maxValues','b');
    hold on;
    scatter(w, maxValues(w+128), 'r', 'filled', 'SizeData',10)
   % scatter(w, maxValues(w+128), 'r', 'filled', 'SizeData',10)
    title( ['第',num2str(n),'个权重:', num2str(w)]);
    xlabel('猜测空间');
    ylabel('相关系数');
    grid on;
end


function result = iflessn(~,num1, num2, n)
    % 计算两个数字之间的距离
    distance = abs(num1 - num2);
    
    % 判断距离是否小于 n
    if distance < n
        result = 1;
    else
        result = 0;
    end
end
    end


    methods (Access = public)
        
      function widths = measure_peak_widths(~,signal,locs,N)

    widths = zeros(size(locs));
    
    % 对于每个波峰
    for i = 1:numel(locs)
        % 找到波峰左侧和右侧的下降沿位置
        left_idx = find(signal(1:locs(i)) < N, 1, 'last');
        right_idx = find(signal(locs(i):end) < N, 1, 'first') + locs(i) - 1;
        
        % 计算波峰宽度
             widths(i) = right_idx - left_idx;
    end
         end
        
      % 波峰剔除
function [maxpks_updated, maxIndices_updated] = filter_peak_widths(~,maxpks, maxIndices, widths,W)

    valid_indices = widths >= W;
    maxpks_updated = maxpks(valid_indices);
    maxIndices_updated = maxIndices(valid_indices);
end


     function plotSignalAndEnvelopes(~,signal, upper_envelope, smoothed_upper_envelope,dd)
    % 创建图形
    
    figure;
    if dd==1
    % 绘制原始信号
    subplot(3, 1, 1);
    plot(signal, 'b', 'LineWidth', 1);
    title('原始信号');
    xlabel('样本点');
    ylabel('振幅');

    % 绘制信号上包络
    subplot(3, 1, 2);
    plot(upper_envelope, 'r', 'LineWidth', 1);
    title('信号上包络');
    xlabel('样本点');
    ylabel('振幅');

    % 绘制高斯平滑滤波后的包络
    subplot(3, 1, 3);
    plot(smoothed_upper_envelope, 'b', 'LineWidth', 1);
    title('高斯平滑滤波');
    xlabel('样本点');
    ylabel('振幅');
    else
    plot(smoothed_upper_envelope, 'b', 'LineWidth', 1);
    title('高斯平滑滤波');
    xlabel('样本点');
    ylabel('振幅');
    end
end
      function activation = get_activation_function(type)
    % 根据输入的 type 返回相应的激活函数
    
    switch type
        case 1
            activation = 'relu';
        case 2
            activation = 'sigmoid';
        case 3
            activation = 'tanh';
        case 4
            activation = 'softmax';
        otherwise
            activation = 'unknown';
            fprintf('Unsupported activation type: %d\n', type);
    end
end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name='模型反演平台';
        end

        % Button pushed function: Button_6
        function ButtonPushed(app, event)
            selectedFolder  = uigetdir('F:\项目\模型反演\MLP采集\BMLP', '选择功耗曲线');
            if selectedFolder ~= 0
                 app.PTracesPath = selectedFolder;
                 app.EditField_3.Value= selectedFolder;
                % Enable process file button
                app.Button_6.Enable = 'on';
            end
        end

        % Button pushed function: Button_8
        function ButtonPushed2(app, event)
             [Inputfilename, Inputpathname] = uigetfile({'*.txt', 'Text Files (*.txt)'}, '选择输入');
            if Inputfilename ~= 0
                % Save selected file path
                app.SelectedFilePath = fullfile(Inputpathname, Inputfilename);
                 app.EditField_4.Value=  app.SelectedFilePath ;
                % Enable process file button
                app.Button_8.Enable = 'on';
            end
        end

        % Button pushed function: Button_9
        function datapreprocess(app, event)
       
       folderPath=app.PTracesPath;%
       filePattern = fullfile(folderPath, '*.csv');
        csvFiles = dir(filePattern);
        % 初始化总和
        totalCurve = zeros(1, 900000); % 假设曲线长度为500
        % 循环读取每个CSV文件
          h = waitbar(0, '数据预处理...');
         % 高斯平滑滤波+标准化
          for i =1:length(csvFiles)
             file_name = fullfile(folderPath, [num2str(i) '.csv']);
             data = csvread(file_name);
             signal = data(:, 1); % 假设信号在CSV文件的第一列
             
             upper_envelope = envelope(signal);
             temp=smoothdata(upper_envelope,"gaussian",20);
              normalized_trace = zscore(temp'); 
             smoothed_upper_envelope(i,:) = normalized_trace;
            % plotSignalAndEnvelopes(app,signal, upper_envelope, normalized_trace,1)
             waitbar(i/length(csvFiles));
          end
        app.trace_undiv=smoothed_upper_envelope;
        uisave('smoothed_upper_envelope','smoothed_upper_envelope.mat')
          close(h);
        end

        % Button pushed function: Button_10
        function Start_Inver_on(app, event)
            [filename, filepath] = uigetfile('*.mat', '选择分割好的数据');
            matFilePath = fullfile(filepath, filename);
            try
               temp= load(matFilePath);
               PowerTrace= temp.(filename(1:end-4));
            catch
                % 如果出现异常，显示错误信息
               errordlg('加载 .mat 文件时出现错误。');
            end

            %输入
            inputs_file_path =app.SelectedFilePath;
            inputs = dlmread(inputs_file_path);
            %权重
            for i=1:8
                for j=1:64
                      lay_point(i,j)=app.MLP_Arr(8*(j-1)+i);
                end
            end
            %反演
            N=length(inputs(:,1));%输入的一行为一个节点
            Rsum=zeros(N,64);
            Rsum(:,2)=10*inputs(:,1);
            %计算汉明重量
            textvalue=['第一层第一个节点：', '-', '-','-','-','-','-','-','-', '-','-','-', '-' ,'-' ,'-', '-', '-','-','-', '-' ,'-'];
            for n=2:64 %反演第一个节点的64个权重
            for w=-127:127
                for x=1:N
                    temp=w*inputs(x,n)+Rsum(x,n);
            HW(x,w+128) = app.HammingWeight(temp,1);%
                end
            end
            %计算相关系数      
            maxValues1 = app.analyze_w1_correlation(PowerTrace(:,n,:),HW,lay_point(1,n),n);
            Rsum(:,n+1)=Rsum(:,n)+lay_point(1,n)*inputs(:,n);
            maxValues1(128)= maxValues1(128)*0.5;
            [~, Index] = max(maxValues1);
            guess_w=Index-128;
            isright(n)=app.iflessn(guess_w, lay_point(1,n), 3);
            if 1 == isright(n)
            newtextvalue =['第',num2str(n),'个权重的反演值为:',num2str(guess_w),'； 正确值为:',num2str(lay_point(1,n)),'； 是否在误差范围：是'];  
            else
            newtextvalue= ['第',num2str(n),'个权重的反演值为:',num2str(guess_w),'； 正确值为:',num2str(lay_point(1,n)),'； 是否在误差范围：否'];
            end
            newtextvalue = sprintf('\n%s', newtextvalue);
            textvalue = sprintf('%s%s', textvalue, newtextvalue);
            app.TextArea.Value= textvalue;
            end
            %计算正确率
             numOnes = sum(isright == 1);
             ratio = numOnes / length(isright);
             newtextvalue = sprintf('\n%s', ['反演正确率为:',num2str(ratio)]);
             textvalue = sprintf('%s%s', textvalue, newtextvalue);
             app.TextArea.Value= textvalue;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            % 关闭所有打开的图形窗口
            close all;
            clear app;
              close(h);
              stop(task); 
            % 关闭主窗口
            delete(app.UIFigure);
        end

        % Button pushed function: Button_11
        function data_div(app, event)
         load('F:\项目\模型反演\MLP采集\BMLP\权重和输入\SLTMMLPNet.mat');
            [filename, filepath] = uigetfile('*.mat', '选择预处理以后的 .mat 文件');%这里选择预处理以后的文件
            matFilePath = fullfile(filepath, filename);
            try
                h1=msgbox('数据加载中，请耐心等待！');
               temp= load(matFilePath);
               tempp= temp.(filename(1:end-4));
              
            catch
                % 如果出现异常，显示错误信息
               errordlg('加载 .mat 文件时出现错误。');
            end
          
% 运行训练好的lstm模型，生成标签掩膜
load('F:\项目\模型反演\MLP采集\BMLP\权重和输入\SLTMMLPNet.mat');

% 可视化预测结果
num = 240;
predictedLabels = classify(rawNet, tempp(num,:)); % 使用 rawNet 对数据进行分类
Mpred = signalMask(predictedLabels);
Mpred.SpecifySelectedCategories = true;
Mpred.SelectedCategories = find(Mpred.Categories ~= "n_a");
figure;

plotsigroi(Mpred, tempp(num,:));
title('Predicted');
% 显示结构反演结果
app.MLP_structure.numoflayer=5;
app.MLP_structure.nodes=[64; 8; 8; 10; 10];
app.MLP_structure.actfun= ["/", "relu", "relu", "relu", "softmax"];

ture_numoflayer = 5; % 总层数
ture_nodes = [64; 8; 8; 10; 10];
ture_actfun = ["/", "relu", "relu", "relu", "softmax"];
true_resultstring = ''; % 初始化结果字符串
for i = 1:numoflayer
    if i == 1
        trueresultstring = ['输入层(' num2str(nodes(1)) ') -' char(actfun(i)) '||'];
    else
        trueresultstring = [trueresultstring '第' num2str(i) '层(' num2str(nodes(i)) ')' '-' char(actfun(i)) '||'];
    end
    if i == numoflayer
        trueresultstring = [trueresultstring '输入层' num2str(nodes(i)) ')' '-' char(actfun(i))];
    end
end
app.EditField_17.Value = trueresultstring;


% tample=tempp(1,2669:102465);
%    h = waitbar(0, '正在进行分割...');
% for i=1:500
% 
%     [istart,istop] = findsignal(tempp(i,2000:106000), tample,'TimeAlignment','dtw','Metric','absolute', ...
%     'MaxNumSegments',1);
%     metche_1_64(i,:)=[istart,istop];      
%  figure; 
%  plot(tempp(i,istart:istop)); 
% waitbar(i/1000);
% end
       [filename, filepath] = uigetfile('*.mat', '选择第一次模型分层文件 .mat 文件');
            matFilePath = fullfile(filepath, filename);
            try
               temp= load(matFilePath);
               metche_1_64= temp.(filename(1:end-4));
            catch
                % 如果出现异常，显示错误信息
               errordlg('加载 .mat 文件时出现错误。');
            end


% 把每个乘法分割开
for i=1:500
  waitbar(500+i/1000);
    minpks=0;
    minIndices=0;
    maxpks=0;
    maxIndices=0;
    temp=tempp(i,metche_1_64(i,1):metche_1_64(i,2));%取第一段
    [minpks,minIndices] = findpeaks(4-temp,'minpeakdistance',150,'MinPeakHeight',5.50);%1 波谷要大于5.5
    [maxpks,maxIndices] = findpeaks(temp,'minpeakdistance',500,'MinPeakHeight',1.3);%2 波峰要大于1.3
    widths = measure_peak_widths(app,temp,maxIndices,-1.3);%3 波峰在-1.3的脉宽要大于450
    [maxpks_updated, maxIndices_updated] = filter_peak_widths(app,maxpks, maxIndices, widths,450); 


n0=1;
for m = 2:length(minIndices)

    %4 如果俩个波谷间距小于244那么右边那个是分割线
    diffF = minIndices(m) - minIndices(m-1);
    if (diffF < 244) && all(max(temp(minIndices(m-1):minIndices(m))) < 0.5)
        combinations(i,n0) = [minIndices(m)];
        n0=n0+1;
    end
end


n=1;
for j=1:length(minIndices)
    if j==length(minIndices)
        start(i,n)=minIndices(j);%%5 最后一个波谷是分割线
        break;
    end
logicalIndex = (minIndices(j)<maxIndices_updated) & (maxIndices_updated<minIndices(j+1));%6 相邻俩波谷中间若有波峰则，右边那个波谷是分割线
exists = any(logicalIndex);
    if exists
        start(i,n)=minIndices(j);
        n=n+1;
    else
        continue;
    end
end

%

filtered_start=[];
index=[];
if i==5
    i=5;
end
for i1 = 1:nnz(start(i,:))
    % 在 minIndices 中查找与 start 中的值匹配的索引
    if start(i,i1)~=0
    index(i1) = find(minIndices == start(i,i1));
        if ~isempty(index)
        start_pks(i1) = minpks(index(i1));
        end
    end
end
    

m=2;
 Start(i,1)=start(i,1);
for i1=2:nnz(start(i,:))-1
    % 如果找到匹配的索引，则将对应的峰值存储到 start_pks 中
        if minpks(index(i1)) >= 5.634 || (start(i,i1+1)-start(i,i1-1))>2660%2660 %7 保留波谷大于5.634 或
            Start(i,m)=start(i,i1);
          m=m+1;
        end
end
 Start(i,m)=start(i,nnz(start(i,:)));

new_array=[];
for i1 = 1:nnz(combinations(i,:))
    % 如果 combinations 中的元素不在 Start 中，则将其加入新数组
    if ~ismember(combinations(i,i1), Start(i,:))
        new_array = [new_array, combinations(i,i1)];
    end
end
tt=[];
% 将 Start 数组和新数组进行拼接
tt= [Start(i,:), new_array];
tt = tt(tt ~= 0);
tt=sort(tt);

for m = 2:length(tt)%分割线判断
    % 两分割线间距大于400
    diffF = tt(m) - tt(m-1);
    if diffF < 450
        tt(m-1)=0;
    end
end    
% 
% for m = 2:length(tt)-1
%     downtemp=4-temp;
%     [pks,Indices] = findpeaks(downtemp(tt(m)-60:tt(m)+60),'minpeakdistance',1);%波峰要大于1.3
%     [sortedValues, sortedIndices] = sort(pks, 'descend');
% top3Values = sortedValues(1:2);
% variance = var(top3Values);
%     %minwidths = measure_peak_widths(downtemp,tt(m),downtemp(tt(m))-0.4);
%  if variance>0.07
%      tt(m)=0;
%  end 
% end
bb=[];
bb=tt(tt ~= 0);
% for j=3:numel(bb)
%    inter2= bb(j)-bb(j-1);
%    inter1= bb(j-1)-bb(j-2);
%    if inter1<900&&inter2<900
%        bb(j-1)=0;
%    end
%      if inter1<1340&&inter2<600
%        bb(j-1)=0;
%      end
%        if inter1<890&&inter2<1010
%        bb(j-1)=0;
%        end
% 
%        if inter2<600
%            bb(j-1)=0;
%        end
% 
% end
gaps = diff(bb);
[min_gaps, min_indices] = sort(gaps);

if numel(gaps)>64
    nn=numel(gaps)-64;
min_indices = min_indices(1:nn);
for m=1:nn
bb(min_indices(m))=0;
end

end

if numel(gaps)<64
    nn=64-numel(gaps);
min_indices = min_indices(end-nn+1:end);
for m=1:nn
ind =minIndices((minIndices > bb(min_indices(m))) & (minIndices< bb(min_indices(m)+1)));
   gapsa = diff(ind);
[a, b] = sort(gapsa);
b = b(1:nn);
for m=1:nn
bb=sort([bb ind(b(m)+1)]);
end
end
end
cc=[];
cc=bb(bb ~= 0);
SStart0(i,1:numel(cc))  = cc;
%画图

% figure;
% plot(tempp(i,1:106000)); 
% 
% figure;
% plot(tempp(i,metche_1_64(i,1):106000)); 
% hold on;
% stem(minIndices, minpks, 'r', 'filled'); % 使用红色填充的.stem图形
% hold on;
% stem(maxIndices_updated, maxpks_updated, 'y', 'filled'); % 使用红色填充的.stem图形
% % hold on;
% % stem( combinations(i,:),ones(1,length(combinations(i,:))), 'g', 'filled'); 
% hold on;
% stem( SStart0(i,:),ones(1,length(SStart0(i,:))), 'b', 'filled'); 
% 
% %添加图例和标签
% legend('波形', '最大值点');
% xlabel('样本索引');
% ylabel('幅度');
% title('波形及其最大值点');
% hold off;


%close(h);
end
for i = 1:500
    for j = 1:64
   
        temp = tempp(i, metche_1_64(i, 1):metche_1_64(i, 2));
        % 计算右侧的大小
        right_size = SStart0(i, j+1) - SStart0(i, j) + 1;
        PowerTrace_1_64_5_v1(i, j, 1:right_size) = temp(SStart0(i, j):SStart0(i, j+1));
    end
end

        uisave('PowerTrace_1_64_5_v1','PowerTrace_1_64_5_v1.mat')
        end

        % Button pushed function: Button_7
        function shoujiboxing(app, event)
               [Inputfilename, Inputpathname] = uigetfile({'*.py', 'Text Files (*.py)'}, '选择波形采集脚本');
            if Inputfilename ~= 0
                % Save selected file path
                 pyfilename=fullfile(Inputpathname, Inputfilename);
                % Enable process file button
                app.Button_7.Enable = 'on';
            end

            app.SelectedFilePath_py =pyfilename;
           command = ['python ', pyfilename];
            system(command);
        end

        % Callback function
        function ghqxcj_arm_S(app, event)
            [Inputfilename, Inputpathname] = uigetfile({'*.py', 'Text Files (*.py)'}, '选择功耗曲线采集脚本');
            if Inputfilename ~= 0
                % Save selected file path
                 pyfilename=fullfile(Inputpathname, Inputfilename);
                % Enable process file button
                app.Button_13.Enable = 'on';
            end

            app.SelectedFilePath_py_arm_S =pyfilename;
           command = ['python ', pyfilename];
            system(command);
        end

        % Callback function
        function ghqxcj_arm_M(app, event)
            [Inputfilename, Inputpathname] = uigetfile({'*.py', 'Text Files (*.py)'}, '选择功耗曲线采集脚本');
            if Inputfilename ~= 0
                % Save selected file path
                 pyfilename=fullfile(Inputpathname, Inputfilename);
                % Enable process file button
                app.Button_19.Enable = 'on';
            end

            app.SelectedFilePath_py_arm_M =pyfilename;
           command = ['python ', pyfilename];
            system(command);
        end

        % Callback function
        function ghqxcj_arm_Y(app, event)
            [Inputfilename, Inputpathname] = uigetfile({'*.py', 'Text Files (*.py)'}, '选择功耗曲线采集脚本');
            if Inputfilename ~= 0
                % Save selected file path
                 pyfilename=fullfile(Inputpathname, Inputfilename);
                % Enable process file button
                app.Button_25.Enable = 'on';
            end
            app.SelectedFilePath_py_arm_M =pyfilename;
           command = ['python ', pyfilename];
            system(command);
        end

        % Callback function
        function ghqxcj_x86_S(app, event)
            [Inputfilename, Inputpathname] = uigetfile({'*.py', 'Text Files (*.py)'}, '选择功耗曲线采集脚本');
            if Inputfilename ~= 0
                % Save selected file path
                 pyfilename=fullfile(Inputpathname, Inputfilename);
                % Enable process file button
                app.Button_31.Enable = 'on';
            end
            app.SelectedFilePath_py_x86_M =pyfilename;
           command = ['python ', pyfilename];
            system(command); 
        end

        % Callback function
        function ghqxcj_x86_M(app, event)
            [Inputfilename, Inputpathname] = uigetfile({'*.py', 'Text Files (*.py)'}, '选择功耗曲线采集脚本');
            if Inputfilename ~= 0
                % Save selected file path
                 pyfilename=fullfile(Inputpathname, Inputfilename);
                % Enable process file button
                app.Button_37.Enable = 'on';
            end
            app.SelectedFilePath_py_x86_M =pyfilename;
           command = ['python ', pyfilename];
            system(command); 
        end

        % Callback function
        function ghqxcj_x86_Y(app, event)
            [Inputfilename, Inputpathname] = uigetfile({'*.py', 'Text Files (*.py)'}, '选择功耗曲线采集脚本');
            if Inputfilename ~= 0
                % Save selected file path
                 pyfilename=fullfile(Inputpathname, Inputfilename);
                % Enable process file button
                app.Button_43.Enable = 'on';
            end
            app.SelectedFilePath_py_x86_Y =pyfilename;
           command = ['python ', pyfilename];
            system(command);   
        end

        % Callback function
        function xzghqx_arm_S(app, event)
            selectedFolder  = uigetdir('F:\项目\模型反演\MLP采集\BMLP', '选择功耗曲线');
            if selectedFolder ~= 0
                 app.PTracesPath_arm_S = selectedFolder;
                 app.EditField_5.Value= selectedFolder;
                % Enable process file button
                app.Button_12.Enable = 'on';
            end
        end

        % Callback function
        function xzghqx_arm_MM(app, event)
            
        end

        % Callback function
        function xzghqx_arm_M(app, event)
                    selectedFolder  = uigetdir('F:\项目\模型反演\MLP采集\BMLP', '选择功耗曲线');
            if selectedFolder ~= 0
                 app.PTracesPath_arm_M = selectedFolder;
                 app.EditField_7.Value= selectedFolder;
                % Enable process file button
                app.Button_18.Enable = 'on';
            end
        end

        % Callback function
        function xzghqx_arm_Y(app, event)
              selectedFolder  = uigetdir('F:\项目\模型反演\MLP采集\BMLP', '选择功耗曲线');
            if selectedFolder ~= 0
                 app.PTracesPath_arm_Y = selectedFolder;
                 app.EditField_9.Value= selectedFolder;
                % Enable process file button
                app.Button_24.Enable = 'on';
            end
        end

        % Callback function
        function xzghqx_x86_S(app, event)
                   selectedFolder  = uigetdir('F:\项目\模型反演\MLP采集\BMLP', '选择功耗曲线');
            if selectedFolder ~= 0
                 app.PTracesPath_x86_S = selectedFolder;
                 app.EditField_11.Value= selectedFolder;
                % Enable process file button
                app.Button_30.Enable = 'on';
            end 
        end

        % Callback function
        function xzghqx_x86_M(app, event)
             selectedFolder  = uigetdir('F:\项目\模型反演\MLP采集\BMLP', '选择功耗曲线');
            if selectedFolder ~= 0
                 app.PTracesPath_x86_M = selectedFolder;
                 app.EditField_13.Value= selectedFolder;
                % Enable process file button
                app.Button_36.Enable = 'on';
            end 
        end

        % Callback function
        function xzghqx_x86_Y(app, event)
             selectedFolder  = uigetdir('F:\项目\模型反演\MLP采集\BMLP', '选择功耗曲线');
            if selectedFolder ~= 0
                 app.PTracesPath_x86_Y = selectedFolder;
                 app.EditField_15.Value= selectedFolder;
                % Enable process file button
                app.Button_42.Enable = 'on';
            end  
        end

        % Callback function
        function xzsr_arm_S(app, event)
             [Inputfilename, Inputpathname] = uigetfile({'*.txt', 'Text Files (*.txt)'}, '选择输入');
            if Inputfilename ~= 0
                % Save selected file path
                app.SelectedFilePath_arm_S = fullfile(Inputpathname, Inputfilename);
                 app.EditField_6.Value=  app.SelectedFilePath ;
                % Enable process file button
                app.Button_14.Enable = 'on';
            end
        end

        % Callback function
        function xzsr_arm_M(app, event)
                    [Inputfilename, Inputpathname] = uigetfile({'*.txt', 'Text Files (*.txt)'}, '选择输入');
            if Inputfilename ~= 0
                % Save selected file path
                app.SelectedFilePath_arm_M = fullfile(Inputpathname, Inputfilename);
                 app.EditField_8.Value=  app.SelectedFilePath ;
                % Enable process file button
                app.Button_20.Enable = 'on';
            end
        end

        % Callback function
        function xzsr_arm_Y(app, event)
             [Inputfilename, Inputpathname] = uigetfile({'*.txt', 'Text Files (*.txt)'}, '选择输入');
            if Inputfilename ~= 0
                % Save selected file path
                app.SelectedFilePath_arm_Y = fullfile(Inputpathname, Inputfilename);
                 app.EditField_10.Value=  app.SelectedFilePath ;
                % Enable process file button
                app.Button_26.Enable = 'on';
            end
        end

        % Callback function
        function xzsr_x86_S(app, event)
             [Inputfilename, Inputpathname] = uigetfile({'*.txt', 'Text Files (*.txt)'}, '选择输入');
            if Inputfilename ~= 0
                % Save selected file path
                app.SelectedFilePath_arm_S = fullfile(Inputpathname, Inputfilename);
                 app.EditField_12.Value=  app.SelectedFilePath ;
                % Enable process file button
                app.Button_32.Enable = 'on';
            end    
        end

        % Callback function
        function xzsr_x86_M(app, event)
                       [Inputfilename, Inputpathname] = uigetfile({'*.txt', 'Text Files (*.txt)'}, '选择输入');
            if Inputfilename ~= 0
                % Save selected file path
                app.SelectedFilePath_arm_M = fullfile(Inputpathname, Inputfilename);
                 app.EditField_14.Value=  app.SelectedFilePath ;
                % Enable process file button
                app.Button_38.Enable = 'on';
            end  
        end

        % Callback function
        function xzsr_x86_Y(app, event)
                       [Inputfilename, Inputpathname] = uigetfile({'*.txt', 'Text Files (*.txt)'}, '选择输入');
            if Inputfilename ~= 0
                % Save selected file path
                app.SelectedFilePath_arm_Y = fullfile(Inputpathname, Inputfilename);
                 app.EditField_16.Value=  app.SelectedFilePath ;
                % Enable process file button
                app.Button_44.Enable = 'on';
            end  
        end

        % Callback function
        function sjycl_arm_S(app, event)
              folderPath=app.PTracesPath_arm_M;%
             filePattern = fullfile(folderPath, '*.csv');
            csvFiles = dir(filePattern);
              filename = fullfile(folderPath, csvFiles(i).name); % 获取当前文件的完整路径
             data = csvread(filename); % 读取CSV文件中的数据
              fileLength = size(data, 1); % 获取当前文件中数据的长度

             % 初始化总和
              totalCurve = zeros(1, fileLength); 
            % 循环读取每个CSV文件
            h = waitbar(0, '数据预处理...');
            % 高斯平滑滤波+标准化
          for i =1:length(csvFiles)
             file_name = fullfile(folderPath, [num2str(i) '.csv']);
             data = csvread(file_name);
             signal = data(:, 1); % 假设信号在CSV文件的第一列
             
             upper_envelope = envelope(signal);
             temp=smoothdata(upper_envelope,"gaussian",20);
              normalized_trace = zscore(temp'); 
             smoothed_upper_envelope(i,:) = normalized_trace;
            % plotSignalAndEnvelopes(app,signal, upper_envelope, normalized_trace,1)
             waitbar(i/length(csvFiles));
          end
        app.trace_undiv=smoothed_upper_envelope;
        uisave('smoothed_upper_envelope','smoothed_upper_envelope.mat')
          close(h);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ARMTab = uitab(app.TabGroup);
            app.ARMTab.Title = 'ARM';
            app.TabGroup2 = uitabgroup(app.ARMTab);
            app.MLPTab_2 = uitab(app.TabGroup2);
            app.MLPTab_2.Title = 'MLP';
            app.Panel = uipanel(app.MLPTab_2);
            app.Panel.Title = '采集或选择功耗曲线';
            app.Button_6 = uibutton(app.Panel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_6.Text = '选择功耗曲线';
            app.EditField_3 = uieditfield(app.Panel, 'text');
            app.Button_7 = uibutton(app.Panel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @shoujiboxing, true);
            app.Button_7.Text = '功耗曲线采集';
            app.Button_9 = uibutton(app.Panel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @datapreprocess, true);
            app.Button_9.Text = '数据预处理';
            app.Button_8 = uibutton(app.Panel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button_8.Text = '选择输入';
            app.EditField_4 = uieditfield(app.Panel, 'text');
            app.Panel_2 = uipanel(app.MLPTab_2);
            app.Panel_2.Title = '模型反演';
            app.Button_10 = uibutton(app.Panel_2, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Start_Inver_on, true);
            app.Button_10.Text = '参数反演';
            app.TextArea = uitextarea(app.Panel_2);
            app.Button_11 = uibutton(app.Panel_2, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @data_div, true);
            app.Button_11.Text = '结构反演';
            app.EditField_17 = uieditfield(app.Panel_2, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelExtract

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end